classdef projektfale < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Menu                    matlab.ui.container.Menu
        InformacjeMenu          matlab.ui.container.Menu
        TabGroup                matlab.ui.container.TabGroup
        FalatumionaTab          matlab.ui.container.Tab
        naglowektl              matlab.ui.control.TextArea
        omegatl                 matlab.ui.control.NumericEditField
        EditField_10Label_2     matlab.ui.control.Label
        MuzykaON_2              matlab.ui.control.Button
        rownaniefalitl          matlab.ui.control.TextArea
        beta                    matlab.ui.control.NumericEditField
        EditField_10Label       matlab.ui.control.Label
        amplitudatl             matlab.ui.control.Slider
        ASliderLabel            matlab.ui.control.Label
        MuzykaON                matlab.ui.control.Button
        Generuj                 matlab.ui.control.Button
        rownanieogolnetl        matlab.ui.control.TextArea
        wykresfalitl            matlab.ui.control.UIAxes
        TensamkierunekTab       matlab.ui.container.Tab
        naglowekrown            matlab.ui.control.TextArea
        Generujfalrown          matlab.ui.control.Button
        rownfalirown            matlab.ui.control.TextArea
        amplituda2rown          matlab.ui.control.Slider
        A2SliderLabel           matlab.ui.control.Label
        amplituda1rown          matlab.ui.control.Slider
        A1SliderLabel           matlab.ui.control.Label
        faza1rown               matlab.ui.control.NumericEditField
        Label_2                 matlab.ui.control.Label
        faza2rown               matlab.ui.control.NumericEditField
        EditField_6Label        matlab.ui.control.Label
        rownanieogolnerown      matlab.ui.control.TextArea
        omega2rown              matlab.ui.control.NumericEditField
        EditField_5Label        matlab.ui.control.Label
        omega1rown              matlab.ui.control.NumericEditField
        Label                   matlab.ui.control.Label
        wykresfalrown           matlab.ui.control.UIAxes
        KierunkiprostopadeTab   matlab.ui.container.Tab
        naglowekpros            matlab.ui.control.TextArea
        amplituda2pros          matlab.ui.control.Slider
        A2Slider_2Label         matlab.ui.control.Label
        amplituda1pros          matlab.ui.control.Slider
        A1Slider_2Label         matlab.ui.control.Label
        omega1pros              matlab.ui.control.NumericEditField
        Label_3                 matlab.ui.control.Label
        omega2pros              matlab.ui.control.NumericEditField
        EditField_7Label        matlab.ui.control.Label
        generujpros             matlab.ui.control.Button
        rownaniefalipros        matlab.ui.control.TextArea
        rownanieogolnefalipros  matlab.ui.control.TextArea
        faza2pros               matlab.ui.control.NumericEditField
        EditField_8Label        matlab.ui.control.Label
        faza1pros               matlab.ui.control.NumericEditField
        Label_4                 matlab.ui.control.Label
        wykresfalpros           matlab.ui.control.UIAxes
        PolaryzacjaTab          matlab.ui.container.Tab
        naglowekpol             matlab.ui.control.TextArea
        amplitudaypol           matlab.ui.control.Slider
        A2Slider_2Label_2       matlab.ui.control.Label
        amplitudaxpol           matlab.ui.control.Slider
        A1Slider_2Label_2       matlab.ui.control.Label
        omegapol                matlab.ui.control.NumericEditField
        Label_6                 matlab.ui.control.Label
        kpol                    matlab.ui.control.NumericEditField
        EditField_7Label_2      matlab.ui.control.Label
        generujpol              matlab.ui.control.Button
        rownaniepol             matlab.ui.control.TextArea
        rownanieogolnepol       matlab.ui.control.TextArea
        fazaypol                matlab.ui.control.NumericEditField
        EditField_8Label_2      matlab.ui.control.Label
        fazaxpol                matlab.ui.control.NumericEditField
        Label_5                 matlab.ui.control.Label
        wykresfalspol           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        piosenka
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: InformacjeMenu
        function InformacjeMenuSelected(app, event)

            % tekst z danymi
            omnie = sprintf([' Autor Aplikacji: Mateusz Nogaj \n \n Adres Email: matnogaj@student.agh.edu.pl ' ...
                '\n \n Temat aplikacji: Fale']);
            
            % ikonka informacji
            uialert(app.UIFigure, omnie, 'O mnie', 'Icon', 'info');

        end

        % Button pushed function: Generuj
        function GenerujButtonPushed(app, event)

            % wzór fali tłumionej
            fun = @(t)  app.amplitudatl.Value.*exp(-app.beta.Value.*t).*cos(t.*sqrt...
                (app.omegatl.Value^2-app.beta.Value^2));

            % wzór obwiedni dodatniej
            funobw = @(t) app.amplitudatl.Value*exp(-app.beta.Value.*t);

            % generowanie nadpisywanego wykresu oraz obwiedni dodatniej i
            % ujemnej z odpowiednimi granicami, aby wykres się mieścił
            t=linspace(0,15,200);
            for i=2:1:200
            plot(app.wykresfalitl, t(1, 1:i), fun(t(1, 1:i)), 'LineWidth', 3, 'Color','r');
            hold(app.wykresfalitl, 'on');
            plot(app.wykresfalitl, t, funobw(t), '--');
            plot(app.wykresfalitl, t, -funobw(t), '--');

            xlim(app.wykresfalitl, [0, t(1, i)+1]);
            ylim(app.wykresfalitl, [-1.1*app.amplitudatl.Value, 1.1*app.amplitudatl.Value]);

            app.wykresfalitl.Title.String = "Fala tłumiona";
            pause(0.001);
            hold(app.wykresfalitl, 'off');
            end
           
        end

        % Value changed function: omegatl
        function omegatlValueChanged(app, event)

        % nadpisywanie aktualnego równania fali dla wybranych parametrów
    app.rownaniefalitl.Value = ['Y = ', num2str(app.amplitudatl.Value,3),' * exp(',...
        num2str(-app.beta.Value),'t) * cos(', num2str(sqrt(app.omegatl.Value^2 - app.beta.Value^2),4), 't)'];

        end

        % Value changed function: beta
        function betaValueChanged(app, event)

        % nadpisywanie aktualnego równania fali dla wybranych parametrów
    app.rownaniefalitl.Value = ['Y = ', num2str(app.amplitudatl.Value,3),' * exp(',...
        num2str(-app.beta.Value),'t) * cos(', num2str(sqrt(app.omegatl.Value^2 - app.beta.Value^2),4), 't)'];

        end

        % Value changed function: amplitudatl
        function amplitudatlValueChanged(app, event)

        % nadpisywanie aktualnego równania fali dla wybranych parametrów
    app.rownaniefalitl.Value = ['Y = ', num2str(app.amplitudatl.Value,3),' * exp(',...
        num2str(-app.beta.Value),'t) * cos(', num2str(sqrt(app.omegatl.Value^2 - app.beta.Value^2),4), 't)'];

        end

        % Button pushed function: MuzykaON
        function MuzykaONButtonPushed(app, event)

            % wczytanie piosenki i puszczenie
            [dane, f] = audioread("muzyka_projekt.wav");
            app.piosenka = audioplayer(dane, f);
            play(app.piosenka);
            
        end

        % Button pushed function: MuzykaON_2
        function MuzykaON_2ButtonPushed(app, event)

            % zatrzymanie piosenki
            stop(app.piosenka);

        end

        % Button pushed function: Generujfalrown
        function GenerujfalrownButtonPushed(app, event)
            
            % wzory zespolone na obie fale o kierunkach równoległych
            fun1 = @(t)  app.amplituda1rown.Value.*exp...
            (1i*(app.omega1rown.Value.*t + app.faza1rown.Value));

            fun2 = @(t)  app.amplituda2rown.Value.*exp...
            (1i*(app.omega2rown.Value.*t + app.faza2rown.Value));

            % wzór obwiedni dodatniej - moduł z sumy funkcji
            funobwr = @(t) abs(app.amplituda1rown.Value.*exp...
            (1i*(app.omega1rown.Value.*t + app.faza1rown.Value))...
            + app.amplituda2rown.Value.*exp...
            (1i*(app.omega2rown.Value.*t + app.faza2rown.Value)));

            % generowanie nadpisywanego wykresu oraz obwiedni dodatniej i
            % ujemnej z odpowiednimi granicami, aby wykres się mieścił
            t=linspace(0,15,200);
            for n=2:1:200
            plot(app.wykresfalrown, t(1, 1:n), real(fun1(t(1, 1:n))) + real(fun2(t(1, 1:n))), 'LineWidth', 3, 'Color','r');
            hold(app.wykresfalrown, 'on');
            plot(app.wykresfalrown, t(1, 1:n), funobwr(t(1, 1:n)), '--');
            plot(app.wykresfalrown, t(1, 1:n), -funobwr(t(1, 1:n)), '--');

            xlim(app.wykresfalrown, [t(1, n)-2*pi, t(1, n)+1]);
            ylim(app.wykresfalrown, [-1.1*(app.amplituda1rown.Value+app.amplituda2rown.Value),...
                1.1*(app.amplituda1rown.Value+app.amplituda2rown.Value)]);

            app.wykresfalrown.Title.String = "Superpozycja fal";
            pause(0.001);
            hold(app.wykresfalrown, 'off');
            end

        end

        % Value changed function: omega2rown
        function omega2rownValueChanged(app, event)
    
        % nadpisywanie aktualnego równania fali dla wybranych parametrów
        app.rownfalirown.Value = ['Y=', num2str(app.amplituda1rown.Value,2),'*exp(i(',...
        num2str(app.omega1rown.Value),'t+', num2str(app.faza1rown.Value), '))+',...
        num2str(app.amplituda2rown.Value,2),'*exp(i(',...
        num2str(app.omega2rown.Value),'t + ', num2str(app.faza2rown.Value), '))'];
            
        end

        % Value changed function: faza2rown
        function faza2rownValueChanged(app, event)

        % nadpisywanie aktualnego równania fali dla wybranych parametrów
        app.rownfalirown.Value = ['Y=', num2str(app.amplituda1rown.Value,2),'*exp(i(',...
        num2str(app.omega1rown.Value),'t+', num2str(app.faza1rown.Value), '))+',...
        num2str(app.amplituda2rown.Value,2),'*exp(i(',...
        num2str(app.omega2rown.Value),'t + ', num2str(app.faza2rown.Value), '))'];

        end

        % Value changed function: faza1rown
        function faza1rownValueChanged(app, event)

        % nadpisywanie aktualnego równania fali dla wybranych parametrów
        app.rownfalirown.Value = ['Y=', num2str(app.amplituda1rown.Value,2),'*exp(i(',...
        num2str(app.omega1rown.Value),'t+', num2str(app.faza1rown.Value), '))+',...
        num2str(app.amplituda2rown.Value,2),'*exp(i(',...
        num2str(app.omega2rown.Value),'t + ', num2str(app.faza2rown.Value), '))'];

        end

        % Value changed function: omega1rown
        function omega1rownValueChanged(app, event)

        % nadpisywanie aktualnego równania fali dla wybranych parametrów
        app.rownfalirown.Value = ['Y=', num2str(app.amplituda1rown.Value,2),'*exp(i(',...
        num2str(app.omega1rown.Value),'t+', num2str(app.faza1rown.Value), '))+',...
        num2str(app.amplituda2rown.Value,2),'*exp(i(',...
        num2str(app.omega2rown.Value),'t + ', num2str(app.faza2rown.Value), '))'];

        end

        % Value changed function: amplituda1rown
        function amplituda1rownValueChanged(app, event)

        % nadpisywanie aktualnego równania fali dla wybranych parametrów
        app.rownfalirown.Value = ['Y=', num2str(app.amplituda1rown.Value,2),'*exp(i(',...
        num2str(app.omega1rown.Value),'t+', num2str(app.faza1rown.Value), '))+',...
        num2str(app.amplituda2rown.Value,2),'*exp(i(',...
        num2str(app.omega2rown.Value),'t + ', num2str(app.faza2rown.Value), '))'];

        end

        % Value changed function: amplituda2rown
        function amplituda2rownValueChanged(app, event)
  
        % nadpisywanie aktualnego równania fali dla wybranych parametrów
        app.rownfalirown.Value = ['Y=', num2str(app.amplituda1rown.Value,2),'*exp(i(',...
        num2str(app.omega1rown.Value),'t+', num2str(app.faza1rown.Value), '))+',...
        num2str(app.amplituda2rown.Value,2),'*exp(i(',...
        num2str(app.omega2rown.Value),'t + ', num2str(app.faza2rown.Value), '))'];
        
        end

        % Button pushed function: generujpros
        function generujprosButtonPushed(app, event)
            
            % wzory na obie fale o kierunkach prostopadłych
            fun1 = @(t)  app.amplituda1pros.Value*cos(app.omega1pros.Value*t + app.faza1pros.Value);

            fun2 = @(t)  app.amplituda2pros.Value*cos(app.omega2pros.Value*t + app.faza2pros.Value);

            % generowanie nadpisywanego wykresu z odpowiednimi granicami, aby wykres się mieścił
            t=linspace(0,15,200);
            for n=2:1:200
            plot(app.wykresfalpros, fun1(t(1, 1:n)),  fun2(t(1, 1:n)), 'LineWidth', 1, 'Color','r');
            hold(app.wykresfalpros, 'on');
            plot(app.wykresfalpros, fun1(t(1, n)), fun2(t(1, n)), 'or', 'LineWidth', 3 );%"pisak"

            xlim(app.wykresfalpros, [-1.1*app.amplituda1pros.Value, 1.1*app.amplituda1pros.Value]);
            ylim(app.wykresfalpros, [-1.1*app.amplituda2pros.Value, 1.1*app.amplituda2pros.Value]);

            app.wykresfalpros.Title.String = "Superpozycja fal";
            pause(0.05);
            hold(app.wykresfalpros, 'off');
            end

        end

        % Value changed function: omega1pros
        function omega1prosValueChanged(app, event)

        % nadpisywanie aktualnego równania fali dla wybranych parametrów
        app.rownaniefalipros.Value = ['X=', num2str(app.amplituda1pros.Value,2), '*cos(',...
        num2str(app.omega1pros.Value, 2),'t+', num2str(app.faza1pros.Value, 3), '), ',...
        'Y = ', num2str(app.amplituda2pros.Value,2), '*cos(',...
        num2str(app.omega2pros.Value, 2),'t+', num2str(app.faza2pros.Value, 3), ')'];

        end

        % Value changed function: omega2pros
        function omega2prosValueChanged(app, event)
            
        % nadpisywanie aktualnego równania fali dla wybranych parametrów
        app.rownaniefalipros.Value = ['X=', num2str(app.amplituda1pros.Value,2), '*cos(',...
        num2str(app.omega1pros.Value, 2),'t+', num2str(app.faza1pros.Value, 3), '), ',...
        'Y = ', num2str(app.amplituda2pros.Value,2), '*cos(',...
        num2str(app.omega2pros.Value, 2),'t+', num2str(app.faza2pros.Value, 3), ')'];

        end

        % Value changed function: faza1pros
        function faza1prosValueChanged(app, event)
            
        % nadpisywanie aktualnego równania fali dla wybranych parametrów
        app.rownaniefalipros.Value = ['X=', num2str(app.amplituda1pros.Value,2), '*cos(',...
        num2str(app.omega1pros.Value, 2),'t+', num2str(app.faza1pros.Value, 3), '), ',...
        'Y = ', num2str(app.amplituda2pros.Value,2), '*cos(',...
        num2str(app.omega2pros.Value, 2),'t+', num2str(app.faza2pros.Value, 3), ')'];

        end

        % Value changed function: faza2pros
        function faza2prosValueChanged(app, event)
            
        % nadpisywanie aktualnego równania fali dla wybranych parametrów
        app.rownaniefalipros.Value = ['X=', num2str(app.amplituda1pros.Value,2), '*cos(',...
        num2str(app.omega1pros.Value, 2),'t+', num2str(app.faza1pros.Value, 3), '), ',...
        'Y = ', num2str(app.amplituda2pros.Value,2), '*cos(',...
        num2str(app.omega2pros.Value, 2),'t+', num2str(app.faza2pros.Value, 3), ')'];

        end

        % Value changed function: amplituda1pros
        function amplituda1prosValueChanged(app, event)
            
        % nadpisywanie aktualnego równania fali dla wybranych parametrów
        app.rownaniefalipros.Value = ['X=', num2str(app.amplituda1pros.Value,2), '*cos(',...
        num2str(app.omega1pros.Value, 2),'t+', num2str(app.faza1pros.Value, 3), '), ',...
        'Y = ', num2str(app.amplituda2pros.Value,2), '*cos(',...
        num2str(app.omega2pros.Value, 2),'t+', num2str(app.faza2pros.Value, 3), ')'];

        end

        % Value changed function: amplituda2pros
        function amplituda2prosValueChanged(app, event)
            
        % nadpisywanie aktualnego równania fali dla wybranych parametrów
        app.rownaniefalipros.Value = ['X=', num2str(app.amplituda1pros.Value,2), '*cos(',...
        num2str(app.omega1pros.Value, 2),'t+', num2str(app.faza1pros.Value, 3), '), ',...
        'Y = ', num2str(app.amplituda2pros.Value,2), '*cos(',...
        num2str(app.omega2pros.Value, 2),'t+', num2str(app.faza2pros.Value, 3), ')'];

        end

        % Button pushed function: generujpol
        function generujpolButtonPushed(app, event)

            % wzory na składowe fali spolaryzowanej
            funx = @(z, t)  app.amplitudaxpol.Value*cos(app.kpol.Value*z-app.omegapol.Value*t+app.fazaxpol.Value);

            funy = @(z, t)  app.amplitudaypol.Value*cos(app.kpol.Value*z-app.omegapol.Value*t+app.fazaypol.Value);

            z = 1:1:200;

            % generowanie nadpisywanego wykresu z odpowiednimi granicami, aby wykres się mieścił
            t=linspace(0,15,200);
            for n=2:1:200
            plot3(app.wykresfalspol, funx(z(1, 1:n), t(1, 1:n)),  funy(z(1, 1:n), t(1, 1:n)), z(1, 1:n), 'LineWidth', 1, 'Color','r');
            hold(app.wykresfalspol, 'on');
            plot3(app.wykresfalspol, funx(z(1, n), t(1, n)), funy(z(1, n), t(1, n)), z(1, n), 'ob', 'LineWidth', 3 );%"pisak"

            xlim(app.wykresfalspol, [-1.1*app.amplitudaxpol.Value, 1.1*app.amplitudaxpol.Value]);
            ylim(app.wykresfalspol, [-1.1*app.amplitudaypol.Value, 1.1*app.amplitudaypol.Value]);
            zlim(app.wykresfalspol, [z(1,1)-1, z(1, n)+2]);

            daspect(app.wykresfalspol, [1 1 1]);

            app.wykresfalspol.Title.String = "Fala spolaryzowana";
            pause(0.0001);
            hold(app.wykresfalspol, 'off');
            end
        end

        % Value changed function: fazaxpol
        function fazaxpolValueChanged(app, event)
         
            % nadpisanie rodzaju polaryzacji
            if (abs(app.fazaypol.Value-app.fazaxpol.Value) - 1.57)/(2*pi) ==...
                    mod(abs(app.fazaypol.Value-app.fazaxpol.Value) - 1.57, 2*pi) &&...
                    abs(app.amplitudaxpol.Value-app.amplitudaypol.Value) < 1

                  app.rownaniepol.Value='kołowa';
            elseif abs(app.fazaypol.Value-app.fazaxpol.Value)/pi ==...
                    mod(abs(app.fazaypol.Value-app.fazaxpol.Value), pi)

                   app.rownaniepol.Value='liniowa';
            else
                   app.rownaniepol.Value='eliptyczna';
                   % fala eliptyczna czasem na wykresie może wyglądać jak
                   % kołowa, gdyż wpłaszczyzna XY jest skalowana do
                   % kwadratu, aby wykres lepiej wyglądał
            end

        end

        % Value changed function: amplitudaxpol
        function amplitudaxpolValueChanged(app, event)
            
            % nadpisanie rodzaju polaryzacji
            if (abs(app.fazaypol.Value-app.fazaxpol.Value) - 1.57)/(2*pi) ==...
                    mod(abs(app.fazaypol.Value-app.fazaxpol.Value) - 1.57, 2*pi) &&...
                    abs(app.amplitudaxpol.Value-app.amplitudaypol.Value) < 1

                  app.rownaniepol.Value='kołowa';
            elseif abs(app.fazaypol.Value-app.fazaxpol.Value)/pi ==...
                    mod(abs(app.fazaypol.Value-app.fazaxpol.Value), pi)

                   app.rownaniepol.Value='liniowa';
            else
                   app.rownaniepol.Value='eliptyczna';
                   % fala eliptyczna czasem na wykresie może wyglądać jak
                   % kołowa, gdyż wpłaszczyzna XY jest skalowana do
                   % kwadratu, aby wykres lepiej wyglądał
            end

        end

        % Value changed function: amplitudaypol
        function amplitudaypolValueChanged(app, event)

            % nadpisanie rodzaju polaryzacji
            if (abs(app.fazaypol.Value-app.fazaxpol.Value) - 1.57)/(2*pi) ==...
                    mod(abs(app.fazaypol.Value-app.fazaxpol.Value) - 1.57, 2*pi) &&...
                    abs(app.amplitudaxpol.Value-app.amplitudaypol.Value) < 1

                  app.rownaniepol.Value='kołowa';
            elseif abs(app.fazaypol.Value-app.fazaxpol.Value)/pi ==...
                    mod(abs(app.fazaypol.Value-app.fazaxpol.Value), pi)

                   app.rownaniepol.Value='liniowa';
            else
                   app.rownaniepol.Value='eliptyczna';
                   % fala eliptyczna czasem na wykresie może wyglądać jak
                   % kołowa, gdyż wpłaszczyzna XY jest skalowana do
                   % kwadratu, aby wykres lepiej wyglądał
            end

        end

        % Value changed function: fazaypol
        function fazaypolValueChanged(app, event)

            % nadpisanie rodzaju polaryzacji
            if (abs(app.fazaypol.Value-app.fazaxpol.Value) - 1.57)/(2*pi) ==...
                    mod(abs(app.fazaypol.Value-app.fazaxpol.Value) - 1.57, 2*pi) &&...
                    abs(app.amplitudaxpol.Value-app.amplitudaypol.Value) < 1

                  app.rownaniepol.Value='kołowa';
            elseif abs(app.fazaypol.Value-app.fazaxpol.Value)/pi ==...
                    mod(abs(app.fazaypol.Value-app.fazaxpol.Value), pi)

                   app.rownaniepol.Value='liniowa';
            else
                   app.rownaniepol.Value='eliptyczna';
                   % fala eliptyczna czasem na wykresie może wyglądać jak
                   % kołowa, gdyż wpłaszczyzna XY jest skalowana do
                   % kwadratu, aby wykres lepiej wyglądał
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.InformacjeMenu = uimenu(app.Menu);
            app.InformacjeMenu.MenuSelectedFcn = createCallbackFcn(app, @InformacjeMenuSelected, true);
            app.InformacjeMenu.Text = 'Informacje';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FalatumionaTab = uitab(app.TabGroup);
            app.FalatumionaTab.Title = 'Fala tłumiona';
            app.wykresfalitl = uiaxes(app.FalatumionaTab);
            title(app.wykresfalitl, 'Fala tłumiona')
            xlabel(app.wykresfalitl, 't')
            ylabel(app.wykresfalitl, 'Y')
            zlabel(app.wykresfalitl, 'Z')
            app.rownanieogolnetl = uitextarea(app.FalatumionaTab);
            app.rownanieogolnetl.Editable = 'off';
            app.rownanieogolnetl.Value = {'Równanie fali'; 'Y = A * exp(-βt) * cos(t *sqrt (ω^2 - β^2))'};
            app.Generuj = uibutton(app.FalatumionaTab, 'push');
            app.Generuj.ButtonPushedFcn = createCallbackFcn(app, @GenerujButtonPushed, true);
            app.Generuj.Text = 'Generuj';
            app.MuzykaON = uibutton(app.FalatumionaTab, 'push');
            app.MuzykaON.ButtonPushedFcn = createCallbackFcn(app, @MuzykaONButtonPushed, true);
            app.MuzykaON.Text = 'Muzyka ON';
            app.ASliderLabel = uilabel(app.FalatumionaTab);
            app.ASliderLabel.Text = 'A';
            app.amplitudatl = uislider(app.FalatumionaTab);
            app.amplitudatl.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.amplitudatl.ValueChangedFcn = createCallbackFcn(app, @amplitudatlValueChanged, true);
            app.EditField_10Label = uilabel(app.FalatumionaTab);
            app.EditField_10Label.Text = 'β';
            app.beta = uieditfield(app.FalatumionaTab, 'numeric');
            app.beta.Limits = [0 1];
            app.beta.ValueChangedFcn = createCallbackFcn(app, @betaValueChanged, true);
            app.rownaniefalitl = uitextarea(app.FalatumionaTab);
            app.rownaniefalitl.Editable = 'off';
            app.MuzykaON_2 = uibutton(app.FalatumionaTab, 'push');
            app.MuzykaON_2.ButtonPushedFcn = createCallbackFcn(app, @MuzykaON_2ButtonPushed, true);
            app.MuzykaON_2.Text = 'Muzyka OFF';
            app.EditField_10Label_2 = uilabel(app.FalatumionaTab);
            app.EditField_10Label_2.Text = 'ω';
            app.omegatl = uieditfield(app.FalatumionaTab, 'numeric');
            app.omegatl.Limits = [1 10];
            app.omegatl.ValueChangedFcn = createCallbackFcn(app, @omegatlValueChanged, true);
            app.omegatl.Value = 1;
            app.naglowektl = uitextarea(app.FalatumionaTab);
            app.naglowektl.Editable = 'off';
            app.naglowektl.Value = {'Dokładne równanie fali:'};
            app.TensamkierunekTab = uitab(app.TabGroup);
            app.TensamkierunekTab.Title = 'Ten sam kierunek';
            app.wykresfalrown = uiaxes(app.TensamkierunekTab);
            title(app.wykresfalrown, 'Superpozycja fal')
            xlabel(app.wykresfalrown, 't')
            ylabel(app.wykresfalrown, 'Y')
            zlabel(app.wykresfalrown, 'Z')
            app.Label = uilabel(app.TensamkierunekTab);
            app.Label.Text = 'ω1';
            app.omega1rown = uieditfield(app.TensamkierunekTab, 'numeric');
            app.omega1rown.Limits = [0 40];
            app.omega1rown.ValueChangedFcn = createCallbackFcn(app, @omega1rownValueChanged, true);
            app.EditField_5Label = uilabel(app.TensamkierunekTab);
            app.EditField_5Label.Text = 'ω2';
            app.omega2rown = uieditfield(app.TensamkierunekTab, 'numeric');
            app.omega2rown.Limits = [0 40];
            app.omega2rown.ValueChangedFcn = createCallbackFcn(app, @omega2rownValueChanged, true);
            app.rownanieogolnerown = uitextarea(app.TensamkierunekTab);
            app.rownanieogolnerown.Editable = 'off';
            app.rownanieogolnerown.Value = {'Równanie fali'; 'Y = A * cos(ω * t + φ)'};
            app.EditField_6Label = uilabel(app.TensamkierunekTab);
            app.EditField_6Label.Text = 'φ2';
            app.faza2rown = uieditfield(app.TensamkierunekTab, 'numeric');
            app.faza2rown.ValueChangedFcn = createCallbackFcn(app, @faza2rownValueChanged, true);
            app.Label_2 = uilabel(app.TensamkierunekTab);
            app.Label_2.Text = 'φ1';
            app.faza1rown = uieditfield(app.TensamkierunekTab, 'numeric');
            app.faza1rown.ValueChangedFcn = createCallbackFcn(app, @faza1rownValueChanged, true);
            app.A1SliderLabel = uilabel(app.TensamkierunekTab);
            app.A1SliderLabel.Text = 'A1';
            app.amplituda1rown = uislider(app.TensamkierunekTab);
            app.amplituda1rown.Limits = [0 99];
            app.amplituda1rown.MajorTicks = [0 20 40 60 80 99];
            app.amplituda1rown.ValueChangedFcn = createCallbackFcn(app, @amplituda1rownValueChanged, true);
            app.A2SliderLabel = uilabel(app.TensamkierunekTab);
            app.A2SliderLabel.Text = 'A2';
            app.amplituda2rown = uislider(app.TensamkierunekTab);
            app.amplituda2rown.Limits = [0 99];
            app.amplituda2rown.MajorTicks = [0 20 40 60 80 99];
            app.amplituda2rown.ValueChangedFcn = createCallbackFcn(app, @amplituda2rownValueChanged, true);
            app.rownfalirown = uitextarea(app.TensamkierunekTab);
            app.rownfalirown.Editable = 'off';
            app.Generujfalrown = uibutton(app.TensamkierunekTab, 'push');
            app.Generujfalrown.ButtonPushedFcn = createCallbackFcn(app, @GenerujfalrownButtonPushed, true);
            app.Generujfalrown.Text = 'Generuj';
            app.naglowekrown = uitextarea(app.TensamkierunekTab);
            app.naglowekrown.Editable = 'off';
            app.naglowekrown.Value = {'Dokładne równanie fali:'};
            app.KierunkiprostopadeTab = uitab(app.TabGroup);
            app.KierunkiprostopadeTab.Title = 'Kierunki prostopadłe';
            app.wykresfalpros = uiaxes(app.KierunkiprostopadeTab);
            title(app.wykresfalpros, 'Superpozycja fal')
            xlabel(app.wykresfalpros, 'X')
            ylabel(app.wykresfalpros, 'Y')
            zlabel(app.wykresfalpros, 'Z')
            app.Label_4 = uilabel(app.KierunkiprostopadeTab);
            app.Label_4.Text = 'φ1';
            app.faza1pros = uieditfield(app.KierunkiprostopadeTab, 'numeric');
            app.faza1pros.ValueChangedFcn = createCallbackFcn(app, @faza1prosValueChanged, true);
            app.EditField_8Label = uilabel(app.KierunkiprostopadeTab);
            app.EditField_8Label.Text = 'φ2';
            app.faza2pros = uieditfield(app.KierunkiprostopadeTab, 'numeric');
            app.faza2pros.ValueChangedFcn = createCallbackFcn(app, @faza2prosValueChanged, true);
            app.rownanieogolnefalipros = uitextarea(app.KierunkiprostopadeTab);
            app.rownanieogolnefalipros.Editable = 'off';
            app.rownanieogolnefalipros.Value = {'Równania fali'; 'Y = A * cos(ω * t + φ)'; 'X = A * cos(ω * t + φ)'};
            app.rownaniefalipros = uitextarea(app.KierunkiprostopadeTab);
            app.rownaniefalipros.Editable = 'off';
            app.generujpros = uibutton(app.KierunkiprostopadeTab, 'push');
            app.generujpros.ButtonPushedFcn = createCallbackFcn(app, @generujprosButtonPushed, true);
            app.generujpros.Text = 'Generuj';
            app.EditField_7Label = uilabel(app.KierunkiprostopadeTab);
            app.EditField_7Label.Text = 'ω2';
            app.omega2pros = uieditfield(app.KierunkiprostopadeTab, 'numeric');
            app.omega2pros.Limits = [0 20];
            app.omega2pros.ValueChangedFcn = createCallbackFcn(app, @omega2prosValueChanged, true);
            app.Label_3 = uilabel(app.KierunkiprostopadeTab);
            app.Label_3.Text = 'ω1';
            app.omega1pros = uieditfield(app.KierunkiprostopadeTab, 'numeric');
            app.omega1pros.Limits = [0 20];
            app.omega1pros.ValueChangedFcn = createCallbackFcn(app, @omega1prosValueChanged, true);
            app.A1Slider_2Label = uilabel(app.KierunkiprostopadeTab);
            app.A1Slider_2Label.Text = 'A1';
            app.amplituda1pros = uislider(app.KierunkiprostopadeTab);
            app.amplituda1pros.Limits = [0 99];
            app.amplituda1pros.MajorTicks = [0 20 40 60 80 99];
            app.amplituda1pros.ValueChangedFcn = createCallbackFcn(app, @amplituda1prosValueChanged, true);
            app.A2Slider_2Label = uilabel(app.KierunkiprostopadeTab);
            app.A2Slider_2Label.Text = 'A2';
            app.amplituda2pros = uislider(app.KierunkiprostopadeTab);
            app.amplituda2pros.Limits = [0 99];
            app.amplituda2pros.MajorTicks = [0 20 40 60 80 99];
            app.amplituda2pros.ValueChangedFcn = createCallbackFcn(app, @amplituda2prosValueChanged, true);
            app.naglowekpros = uitextarea(app.KierunkiprostopadeTab);
            app.naglowekpros.Editable = 'off';
            app.naglowekpros.Value = {'Dokładne równanie fali:'};
            app.PolaryzacjaTab = uitab(app.TabGroup);
            app.PolaryzacjaTab.Title = 'Polaryzacja';
            app.wykresfalspol = uiaxes(app.PolaryzacjaTab);
            title(app.wykresfalspol, 'Fala spolaryzowana')
            xlabel(app.wykresfalspol, 'X')
            ylabel(app.wykresfalspol, 'Y')
            zlabel(app.wykresfalspol, 'Z')
            app.Label_5 = uilabel(app.PolaryzacjaTab);
            app.Label_5.Text = 'φx';
            app.fazaxpol = uieditfield(app.PolaryzacjaTab, 'numeric');
            app.fazaxpol.Limits = [0 3.13];
            app.fazaxpol.ValueChangedFcn = createCallbackFcn(app, @fazaxpolValueChanged, true);
            app.EditField_8Label_2 = uilabel(app.PolaryzacjaTab);
            app.EditField_8Label_2.Text = 'φy';
            app.fazaypol = uieditfield(app.PolaryzacjaTab, 'numeric');
            app.fazaypol.Limits = [0 3.13];
            app.fazaypol.ValueChangedFcn = createCallbackFcn(app, @fazaypolValueChanged, true);
            app.rownanieogolnepol = uitextarea(app.PolaryzacjaTab);
            app.rownanieogolnepol.Editable = 'off';
            app.rownanieogolnepol.Value = {'Równania fali'; 'Y = Ey*cos(k*z-ω*t+φy)'; 'X = Ex*cos(k*z-ω*t+φx)'};
            app.rownaniepol = uitextarea(app.PolaryzacjaTab);
            app.rownaniepol.Editable = 'off';
            app.generujpol = uibutton(app.PolaryzacjaTab, 'push');
            app.generujpol.ButtonPushedFcn = createCallbackFcn(app, @generujpolButtonPushed, true);
            app.generujpol.Text = 'Generuj';
            app.EditField_7Label_2 = uilabel(app.PolaryzacjaTab);
            app.EditField_7Label_2.Text = 'k';
            app.kpol = uieditfield(app.PolaryzacjaTab, 'numeric');
            app.kpol.Limits = [0 1];
            app.Label_6 = uilabel(app.PolaryzacjaTab);
            app.Label_6.Text = 'ω';
            app.omegapol = uieditfield(app.PolaryzacjaTab, 'numeric');
            app.omegapol.Limits = [0 10];
            app.A1Slider_2Label_2 = uilabel(app.PolaryzacjaTab);
            app.A1Slider_2Label_2.Text = 'Ex';
            app.amplitudaxpol = uislider(app.PolaryzacjaTab);
            app.amplitudaxpol.ValueChangedFcn = createCallbackFcn(app, @amplitudaxpolValueChanged, true);
            app.A2Slider_2Label_2 = uilabel(app.PolaryzacjaTab);
            app.A2Slider_2Label_2.Text = 'Ey';
            app.amplitudaypol = uislider(app.PolaryzacjaTab);
            app.amplitudaypol.ValueChangedFcn = createCallbackFcn(app, @amplitudaypolValueChanged, true);
            app.naglowekpol = uitextarea(app.PolaryzacjaTab);
            app.naglowekpol.Editable = 'off';
            app.naglowekpol.Value = {'Rodzaj polaryzacji:'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = projektfale

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end